classdef EW270_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveRecallSetupPanel          matlab.ui.container.Panel
        NoteProfile0calledonstartupLabel  matlab.ui.control.Label
        RecallButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ProfileNumber                 matlab.ui.control.DropDown
        ProfileNumberDropDownLabel    matlab.ui.control.Label
        GlobalSettingsPanel           matlab.ui.container.Panel
        SyncPulseWidthUnits           matlab.ui.control.DropDown
        PulseWidthUnits               matlab.ui.control.DropDown
        SYNCPulseWidth                matlab.ui.control.NumericEditField
        SYNCPulseWidthEditFieldLabel  matlab.ui.control.Label
        PulseWidth                    matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel      matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        RepRate                       matlab.ui.control.NumericEditField
        RepRateEditFieldLabel         matlab.ui.control.Label
        AllChannelsOFFButton          matlab.ui.control.Button
        AllChannelsONButton           matlab.ui.control.Button
        Channel4Panel                 matlab.ui.container.Panel
        delayUnits4                   matlab.ui.control.DropDown
        Delay4                        matlab.ui.control.NumericEditField
        DelayEditField_4Label         matlab.ui.control.Label
        Enabled4                      matlab.ui.control.CheckBox
        Channel3Panel                 matlab.ui.container.Panel
        delayUnits3                   matlab.ui.control.DropDown
        Delay3                        matlab.ui.control.NumericEditField
        DelayEditField_3Label         matlab.ui.control.Label
        Enabled3                      matlab.ui.control.CheckBox
        Channel2Panel                 matlab.ui.container.Panel
        delayUnits2                   matlab.ui.control.DropDown
        Delay2                        matlab.ui.control.NumericEditField
        DelayEditField_2Label         matlab.ui.control.Label
        Enabled2                      matlab.ui.control.CheckBox
        Channel1Panel                 matlab.ui.container.Panel
        delayUnits1                   matlab.ui.control.DropDown
        Delay1                        matlab.ui.control.NumericEditField
        DelayEditFieldLabel           matlab.ui.control.Label
        Enabled1                      matlab.ui.control.CheckBox
        CurrentConfigurationPanel     matlab.ui.container.Panel
        CurrentConfigText             matlab.ui.control.Label
        ReadConfigurationButton       matlab.ui.control.Button
        DeviceSelectionPanel          matlab.ui.container.Panel
        DisconnectButton              matlab.ui.control.Button
        DeviceID                      matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        ScanButton                    matlab.ui.control.Button
        DeviceDropDown                matlab.ui.control.DropDown
        DeviceDropDownLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            ports = serialportlist("available");
            ports = cellstr(ports);
            app.DeviceDropDown.Items = ports;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            baud = 230400;
            global dev;
            dev = serialport(app.DeviceDropDown.Value, baud);
            configureTerminator(dev, "CR/LF");
            writeline(dev, "*IDN?");
            ID = readline(dev);
            app.DeviceID.Text = ID;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global dev;
            dev = [];
            app.DeviceID.Text = "Disconnected";
        end

        % Drop down opening function: DeviceDropDown
        function DeviceDropDownOpening(app, event)
            ScanButtonPushed(app,event);
        end

        % Button pushed function: ReadConfigurationButton
        function ReadConfigurationButtonPushed(app, event)
            global dev;
            writeline(dev, '?');
            txt = {};
            pause(1);
            while dev.NumBytesAvailable > 0
                txt{end+1} = char(readline(dev));
            end
            %txt = append(txt{1:end-1});
            app.CurrentConfigText.Text = txt;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global dev
            dev = [];
            delete(app);
        end

        % Value changed function: Enabled1
        function Enabled1ValueChanged(app, event)
            global dev;
            enabled = app.Enabled1.Value;
            if enabled
                writeline(dev, "EN 0");
            else
                writeline(dev, "DIS 0");
            end
        end

        % Value changed function: Enabled2
        function Enabled2ValueChanged(app, event)
            global dev;
            enabled = app.Enabled2.Value;
            if enabled
                writeline(dev, "EN 1");
            else
                writeline(dev, "DIS 1");
            end
        end

        % Value changed function: Enabled3
        function Enabled3ValueChanged(app, event)
            global dev;
            enabled = app.Enabled3.Value;
            if enabled
                writeline(dev, "EN 2");
            else
                writeline(dev, "DIS 2");
            end
        end

        % Value changed function: Enabled4
        function Enabled4ValueChanged(app, event)
            global dev;
            enabled = app.Enabled4.Value;
            if enabled
                writeline(dev, "EN 3");
            else
                writeline(dev, "DIS 3");
            end
        end

        % Value changed function: Delay1
        function Delay1ValueChanged(app, event)
            global dev;
            delay = app.Delay1.Value;
            units = app.delayUnits1.Value;
            writeline(dev, "DEL 0 "+delay+units);
        end

        % Value changed function: Delay2
        function Delay2ValueChanged(app, event)
            global dev;
            delay = app.Delay2.Value;
            units = app.delayUnits2.Value;
            writeline(dev, "DEL 1 "+delay+units);
        end

        % Value changed function: Delay3
        function Delay3ValueChanged(app, event)
            global dev;
            delay = app.Delay3.Value;
            units = app.delayUnits3.Value;
            writeline(dev, "DEL 2 "+delay+units);
        end

        % Value changed function: Delay4
        function Delay4ValueChanged(app, event)
            global dev;
            delay = app.Delay4.Value;
            units = app.delayUnits4.Value;
            writeline(dev, "DEL 3 "+delay+units);
        end

        % Value changed function: delayUnits1
        function delayUnits1ValueChanged(app, event)
            Delay1ValueChanged(app,event);            
        end

        % Value changed function: delayUnits2
        function delayUnits2ValueChanged(app, event)
            Delay2ValueChanged(app, event);
        end

        % Value changed function: delayUnits3
        function delayUnits3ValueChanged(app, event)
            Delay3ValueChanged(app, event);
        end

        % Value changed function: delayUnits4
        function delayUnits4ValueChanged(app, event)
            Delay4ValueChanged(app, event);
        end

        % Button pushed function: AllChannelsONButton
        function AllChannelsONButtonPushed(app, event)
            global dev;
            writeline(dev, "EN ALL");
            app.Enabled1.Value = 1;
            app.Enabled2.Value = 1;
            app.Enabled3.Value = 1;
            app.Enabled4.Value = 1;
        end

        % Button pushed function: AllChannelsOFFButton
        function AllChannelsOFFButtonPushed(app, event)
            global dev;
            writeline(dev, "DIS ALL");
            app.Enabled1.Value = 0;
            app.Enabled2.Value = 0;
            app.Enabled3.Value = 0;
            app.Enabled4.Value = 0;
        end

        % Value changed function: RepRate
        function RepRateValueChanged(app, event)
            global dev;
            writeline(dev, "REP "+string(app.RepRate.Value));
        end

        % Value changed function: PulseWidth
        function PulseWidthValueChanged(app, event)
            global dev;
            value = app.PulseWidth.Value;
            units = app.PulseWidthUnits.Value;
            writeline(dev, "PW "+string(value)+units);
        end

        % Value changed function: PulseWidthUnits
        function PulseWidthUnitsValueChanged(app, event)
            PulseWidthValueChanged(app, event);
        end

        % Value changed function: SYNCPulseWidth
        function SYNCPulseWidthValueChanged(app, event)
            global dev;
            value = app.SYNCPulseWidth.Value;
            units = app.SyncPulseWidthUnits.Value;
            writeline(dev, "RPW "+string(value)+units);
        end

        % Value changed function: SyncPulseWidthUnits
        function SyncPulseWidthUnitsValueChanged(app, event)
            SYNCPulseWidthValueChanged(app, event)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global dev;
            N = app.ProfileNumber.Value;
            writeline(dev, "STO "+string(N));
        end

        % Button pushed function: RecallButton
        function RecallButtonPushed(app, event)
            global dev;
            N = app.ProfileNumber.Value;
            writeline(dev, "RCL "+string(N));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DeviceSelectionPanel = uipanel(app.UIFigure);
            app.DeviceSelectionPanel.Title = 'Device Selection';
            app.DeviceDropDownLabel = uilabel(app.DeviceSelectionPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DeviceSelectionPanel);
            app.DeviceDropDown.Items = {''};
            app.DeviceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DeviceDropDownOpening, true);
            app.DeviceDropDown.Value = '';
            app.ScanButton = uibutton(app.DeviceSelectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ConnectButton = uibutton(app.DeviceSelectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DeviceID = uilabel(app.DeviceSelectionPanel);
            app.DeviceID.Text = 'Not Connected';
            app.DisconnectButton = uibutton(app.DeviceSelectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.CurrentConfigurationPanel = uipanel(app.UIFigure);
            app.CurrentConfigurationPanel.Title = 'Current Configuration';
            app.ReadConfigurationButton = uibutton(app.CurrentConfigurationPanel, 'push');
            app.ReadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ReadConfigurationButtonPushed, true);
            app.ReadConfigurationButton.Text = 'Read Configuration';
            app.CurrentConfigText = uilabel(app.CurrentConfigurationPanel);
            app.CurrentConfigText.Text = 'Click above to read configuration';
            app.Channel1Panel = uipanel(app.UIFigure);
            app.Channel1Panel.Title = 'Channel 1';
            app.Enabled1 = uicheckbox(app.Channel1Panel);
            app.Enabled1.ValueChangedFcn = createCallbackFcn(app, @Enabled1ValueChanged, true);
            app.Enabled1.Text = 'Enabled';
            app.DelayEditFieldLabel = uilabel(app.Channel1Panel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.Delay1 = uieditfield(app.Channel1Panel, 'numeric');
            app.Delay1.ValueChangedFcn = createCallbackFcn(app, @Delay1ValueChanged, true);
            app.delayUnits1 = uidropdown(app.Channel1Panel);
            app.delayUnits1.Items = {'us', 'ns'};
            app.delayUnits1.ValueChangedFcn = createCallbackFcn(app, @delayUnits1ValueChanged, true);
            app.delayUnits1.Value = 'us';
            app.Channel2Panel = uipanel(app.UIFigure);
            app.Channel2Panel.Title = 'Channel 2';
            app.Enabled2 = uicheckbox(app.Channel2Panel);
            app.Enabled2.ValueChangedFcn = createCallbackFcn(app, @Enabled2ValueChanged, true);
            app.Enabled2.Text = 'Enabled';
            app.DelayEditField_2Label = uilabel(app.Channel2Panel);
            app.DelayEditField_2Label.Text = 'Delay';
            app.Delay2 = uieditfield(app.Channel2Panel, 'numeric');
            app.Delay2.ValueChangedFcn = createCallbackFcn(app, @Delay2ValueChanged, true);
            app.delayUnits2 = uidropdown(app.Channel2Panel);
            app.delayUnits2.Items = {'us', 'ns'};
            app.delayUnits2.ValueChangedFcn = createCallbackFcn(app, @delayUnits2ValueChanged, true);
            app.delayUnits2.Value = 'us';
            app.Channel3Panel = uipanel(app.UIFigure);
            app.Channel3Panel.Title = 'Channel 3';
            app.Enabled3 = uicheckbox(app.Channel3Panel);
            app.Enabled3.ValueChangedFcn = createCallbackFcn(app, @Enabled3ValueChanged, true);
            app.Enabled3.Text = 'Enabled';
            app.DelayEditField_3Label = uilabel(app.Channel3Panel);
            app.DelayEditField_3Label.Text = 'Delay';
            app.Delay3 = uieditfield(app.Channel3Panel, 'numeric');
            app.Delay3.ValueChangedFcn = createCallbackFcn(app, @Delay3ValueChanged, true);
            app.delayUnits3 = uidropdown(app.Channel3Panel);
            app.delayUnits3.Items = {'us', 'ns'};
            app.delayUnits3.ValueChangedFcn = createCallbackFcn(app, @delayUnits3ValueChanged, true);
            app.delayUnits3.Value = 'us';
            app.Channel4Panel = uipanel(app.UIFigure);
            app.Channel4Panel.Title = 'Channel 4';
            app.Enabled4 = uicheckbox(app.Channel4Panel);
            app.Enabled4.ValueChangedFcn = createCallbackFcn(app, @Enabled4ValueChanged, true);
            app.Enabled4.Text = 'Enabled';
            app.DelayEditField_4Label = uilabel(app.Channel4Panel);
            app.DelayEditField_4Label.Text = 'Delay';
            app.Delay4 = uieditfield(app.Channel4Panel, 'numeric');
            app.Delay4.ValueChangedFcn = createCallbackFcn(app, @Delay4ValueChanged, true);
            app.delayUnits4 = uidropdown(app.Channel4Panel);
            app.delayUnits4.Items = {'us', 'ns'};
            app.delayUnits4.ValueChangedFcn = createCallbackFcn(app, @delayUnits4ValueChanged, true);
            app.delayUnits4.Value = 'us';
            app.GlobalSettingsPanel = uipanel(app.UIFigure);
            app.GlobalSettingsPanel.Title = 'Global Settings';
            app.AllChannelsONButton = uibutton(app.GlobalSettingsPanel, 'push');
            app.AllChannelsONButton.ButtonPushedFcn = createCallbackFcn(app, @AllChannelsONButtonPushed, true);
            app.AllChannelsONButton.Text = 'All Channels ON';
            app.AllChannelsOFFButton = uibutton(app.GlobalSettingsPanel, 'push');
            app.AllChannelsOFFButton.ButtonPushedFcn = createCallbackFcn(app, @AllChannelsOFFButtonPushed, true);
            app.AllChannelsOFFButton.Text = 'All Channels OFF';
            app.RepRateEditFieldLabel = uilabel(app.GlobalSettingsPanel);
            app.RepRateEditFieldLabel.Text = 'Rep Rate';
            app.RepRate = uieditfield(app.GlobalSettingsPanel, 'numeric');
            app.RepRate.ValueChangedFcn = createCallbackFcn(app, @RepRateValueChanged, true);
            app.HzLabel = uilabel(app.GlobalSettingsPanel);
            app.HzLabel.Text = 'Hz';
            app.PulseWidthEditFieldLabel = uilabel(app.GlobalSettingsPanel);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.PulseWidth = uieditfield(app.GlobalSettingsPanel, 'numeric');
            app.PulseWidth.ValueChangedFcn = createCallbackFcn(app, @PulseWidthValueChanged, true);
            app.SYNCPulseWidthEditFieldLabel = uilabel(app.GlobalSettingsPanel);
            app.SYNCPulseWidthEditFieldLabel.Text = 'SYNC Pulse Width';
            app.SYNCPulseWidth = uieditfield(app.GlobalSettingsPanel, 'numeric');
            app.SYNCPulseWidth.ValueChangedFcn = createCallbackFcn(app, @SYNCPulseWidthValueChanged, true);
            app.PulseWidthUnits = uidropdown(app.GlobalSettingsPanel);
            app.PulseWidthUnits.Items = {'us', 'ns'};
            app.PulseWidthUnits.ValueChangedFcn = createCallbackFcn(app, @PulseWidthUnitsValueChanged, true);
            app.PulseWidthUnits.Value = 'us';
            app.SyncPulseWidthUnits = uidropdown(app.GlobalSettingsPanel);
            app.SyncPulseWidthUnits.Items = {'us', 'ns'};
            app.SyncPulseWidthUnits.ValueChangedFcn = createCallbackFcn(app, @SyncPulseWidthUnitsValueChanged, true);
            app.SyncPulseWidthUnits.Value = 'us';
            app.SaveRecallSetupPanel = uipanel(app.UIFigure);
            app.SaveRecallSetupPanel.Title = 'Save/Recall Setup';
            app.ProfileNumberDropDownLabel = uilabel(app.SaveRecallSetupPanel);
            app.ProfileNumberDropDownLabel.Text = 'Profile Number';
            app.ProfileNumber = uidropdown(app.SaveRecallSetupPanel);
            app.ProfileNumber.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ProfileNumber.Value = '1';
            app.SaveButton = uibutton(app.SaveRecallSetupPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RecallButton = uibutton(app.SaveRecallSetupPanel, 'push');
            app.RecallButton.ButtonPushedFcn = createCallbackFcn(app, @RecallButtonPushed, true);
            app.RecallButton.Text = 'Recall';
            app.NoteProfile0calledonstartupLabel = uilabel(app.SaveRecallSetupPanel);
            app.NoteProfile0calledonstartupLabel.Text = 'Note: Profile 0 called on startup.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EW270_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end